classdef metadata_wizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MetadataWizardpage13Panel    matlab.ui.container.Panel
        SessionInfoLabel_2           matlab.ui.control.Label
        selectdirectoryButton        matlab.ui.control.Button
        SelectDirectoryTextArea      matlab.ui.control.TextArea
        NextButton                   matlab.ui.control.Button
        MetadataWizardLabel          matlab.ui.control.Label
        MetadataWizardpage23Panel    matlab.ui.container.Panel
        EditField                    matlab.ui.control.EditField
        ErrorMessageLabel            matlab.ui.control.Label
        StartTimeEditField           matlab.ui.control.EditField
        StartTimeEditFieldLabel      matlab.ui.control.Label
        IdentifierEditField          matlab.ui.control.EditField
        IdentifierEditFieldLabel     matlab.ui.control.Label
        SessionIDEditField           matlab.ui.control.EditField
        SessionIDEditFieldLabel      matlab.ui.control.Label
        InstitutionEditFieldLabel    matlab.ui.control.Label
        FirstNameEditField           matlab.ui.control.EditField
        FirstNameEditFieldLabel      matlab.ui.control.Label
        LastNameEditField            matlab.ui.control.EditField
        LastNameEditFieldLabel       matlab.ui.control.Label
        BackButton_2                 matlab.ui.control.Button
        NextButton_2                 matlab.ui.control.Button
        DescriptionTextArea          matlab.ui.control.TextArea
        DescriptionTextAreaLabel     matlab.ui.control.Label
        KeywordsTextArea             matlab.ui.control.TextArea
        KeywordsTextAreaLabel        matlab.ui.control.Label
        SessionInfoLabel             matlab.ui.control.Label
        ExperimenterInfoLabel        matlab.ui.control.Label
        InstitutionEditField         matlab.ui.control.EditField
        MetadataWizardpage33Panel    matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        SavetoTextArea               matlab.ui.control.TextArea
        SavetoTextAreaLabel          matlab.ui.control.Label
        SubjectStrainEditField       matlab.ui.control.EditField
        SubjectStrainEditFieldLabel  matlab.ui.control.Label
        SubjectIDEditField           matlab.ui.control.EditField
        SubjectIDEditFieldLabel      matlab.ui.control.Label
        BackButton_3                 matlab.ui.control.Button
        SubjectSexDropDown           matlab.ui.control.DropDown
        SubjectSexDropDownLabel      matlab.ui.control.Label
        SubjectDOBDatePicker         matlab.ui.control.DatePicker
        SubjectDOBDatePickerLabel    matlab.ui.control.Label
        SubjectSpeciesDropDown       matlab.ui.control.DropDown
        SubjectSpeciesDropDownLabel  matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        SubjectInfoLabel             matlab.ui.control.Label
    end


    methods (Access = private)
        
        function [] = write_json(app)
            metadata = struct;
            metadata.firstName = app.FirstNameEditField.Value;
            metadata.lastName = app.LastNameEditField.Value;
            metadata.institution = app.InstitutionEditField.Value;
            metadata.sessionID = app.SessionIDEditField.Value;
            metadata.identifier = app.IdentifierEditField.Value;
            metadata.startTime = app.StartTimeEditField.Value;
            metadata.keywords = char(app.KeywordsTextArea.Value);
            metadata.description = char(app.DescriptionTextArea.Value);
            metadata.subjectID = app.SubjectIDEditField.Value;
            metadata.subjectSpecies = char(app.SubjectSpeciesDropDown.Value);
            metadata.subjectDOB = app.SubjectDOBDatePicker.Value;
            metadata.subjectSex = app.SubjectSexDropDown.Value;
            metadata.subjectStrain = app.SubjectStrainEditField.Value;

            % Step 2: Convert the MATLAB structure to a JSON formatted string
            jsonString = jsonencode(metadata);
            
            % Step 3: Open a file for writing
            filename = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            %app.Label.Text = app.SelectDirectoryTextArea.Value;
            fileID = fopen(filename, 'w');
                            
            % Step 4: Write the JSON string to the file
            fprintf(fileID, jsonString);
            
            % Step 5: Close the file
            fclose(fileID);
            fileattrib(filename, '-w', 'a'); % Remove write permission for all users
        end
        
        function [] = reset_fields(app)
            app.SelectDirectoryTextArea.Value = '';
            app.FirstNameEditField.Value = '';
            app.LastNameEditField.Value = '';
            app.InstitutionEditField.Value = 'University of Rochester';
            app.SessionIDEditField.Value = '';
            app.IdentifierEditField.Value = '';
            app.StartTimeEditField.Value = '';
            app.KeywordsTextArea.Value = '';
            app.DescriptionTextArea.Value = '';
            app.SubjectIDEditField.Value = '';
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePicker.Value = NaT;
            app.SubjectSexDropDown.Value = 'please select';
            app.SubjectStrainEditField.Value = '';
        end
        
        function [] = read_existing_json(app)
            jsonFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            fileContent = fileread(jsonFile);

            % Decode the JSON content
            metadata = jsondecode(fileContent);
            app.FirstNameEditField.Value = metadata.firstName;
            app.LastNameEditField.Value = metadata.lastName;
            app.InstitutionEditField.Value = metadata.institution;
            app.SessionIDEditField.Value = metadata.sessionID;
            app.IdentifierEditField.Value = metadata.identifier;
            app.StartTimeEditField.Value = metadata.startTime;
            app.KeywordsTextArea.Value = metadata.keywords;
            app.DescriptionTextArea.Value = metadata.description;
            app.SubjectIDEditField.Value = metadata.subjectID;
            app.SubjectSpeciesDropDown.Value = metadata.subjectSpecies;
            app.SubjectDOBDatePicker.Value = datetime(metadata.subjectDOB);
            app.SubjectSexDropDown.Value = metadata.subjectSex;
            app.SubjectStrainEditField.Value = metadata.subjectStrain;
        end
        
        function expData = read_experiment_xml(app)
            xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
            s = xml2struct(xmlFile); % use function from file exchange 
            expData = struct;
            try
                expData.date = char(s.ThorImageExperiment.Date.Attributes.date);
            catch
                expData.date = '';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.MetadataWizardpage23Panel.Visible = 'on';
            app.MetadataWizardpage33Panel.Visible = 'off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            startTime = app.StartTimeEditField.Value;

            error = false;
            if isempty(app.FirstNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter first name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.LastNameEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter last name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.InstitutionEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter institution name.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SessionIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the session ID.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.IdentifierEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the indentifier.', 'Missing Field', 'Icon', 'error');

            elseif isempty(startTime)
                error = true;
                uialert(app.UIFigure, 'Please enter the start time.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.KeywordsTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the keywords.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.DescriptionTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the description.', 'Missing Field', 'Icon', 'error');                
            end

            try
               datetime(startTime,'Format','yyyy-MM-dd HH:mm:ss');
            catch
               error = true;
            end
            
            if ~error
                app.MetadataWizardpage23Panel.Visible = 'off';
                app.MetadataWizardpage33Panel.Visible = 'on'; 
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            error = false;
            if isempty(app.SubjectIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject ID.', 'Missing Field', 'Icon', 'error');

            elseif strcmp(app.SubjectSpeciesDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject species.', 'Missing Field', 'Icon', 'error');
            
            elseif isempty(app.SubjectDOBDatePicker.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            
            elseif strcmp(app.SubjectSexDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject sex.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SubjectStrainEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            end

            if ~error
                write_json(app)
                message = ['metadata written to ' char(app.SelectDirectoryTextArea.Value) '/' 'metadata.json'];
                title = 'Metadata Saved';
                icon = 'success';
                uialert(app.UIFigure, message, title, 'Icon', icon, 'CloseFcn', @reset_app);
            end
            
            function reset_app(src, event)
            app.MetadataWizardpage13Panel.Visible = 'on';
            app.MetadataWizardpage23Panel.Visible = 'off';
            app.MetadataWizardpage33Panel.Visible = 'off';
            reset_fields(app)
            end
        end

        % Callback function: SelectDirectoryTextArea, selectdirectoryButton
        function selectdirectoryButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            selectedDir = uigetdir;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedDir ~= 0
                % Store the selected directory path in the Edit Field
                app.SelectDirectoryTextArea.Value = selectedDir;
                app.SavetoTextArea.Value = fullfile(app.SelectDirectoryTextArea.Value, 'metadata.json');
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.MetadataWizardpage13Panel.Visible = 'on';
            app.MetadataWizardpage23Panel.Visible = 'off';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.SelectDirectoryTextArea.Value == "")
                uialert(app.UIFigure, 'Please select a directory.', 'Missing Field', 'Icon', 'error');
            return;
            end

            jsonFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata.json');
            if isfile(jsonFile)
                selection = uiconfirm(app.UIFigure, ...
                    'Metadata already exists in this folder. Do you wish to update it?', ...
                    'Confirmation', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if strcmp(selection, 'Yes')
                    read_existing_json(app)
                else
                    app.SelectDirectoryTextArea.Value = '';
                    return
                end
            
            else
                xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
                if isfile(xmlFile)
                    xmlData = read_experiment_xml(app);
                    try
                        date = datetime(xmlData.date, 'InputFormat', 'MM/dd/yyyy HH:mm:ss');
                    catch 
                        date = datetime(xmlData.date);
                    end
                    app.IdentifierEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss'); % use the date as the Identifier
                    app.StartTimeEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss');
                %else
                %    app.IdentifierEditField.Value = '';
                end

            end
            app.MetadataWizardpage13Panel.Visible = 'off';
            app.MetadataWizardpage23Panel.Visible = 'on';
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            try
               datetime(value,'Format','yyyy-MM-dd HH:mm:ss');
               app.ErrorMessageLabel.Text = '';
            catch
               app.ErrorMessageLabel.Text = 'Invalid Datetime.';
            end
        end

        % Callback function
        function savepathTextAreaValueChanged(app, event)
            app.savepathTextArea.Value = fullfile(app.SelectDirectoryTextArea.Value, 'metadata.json');
        end

        % Value changed function: SavetoTextArea
        function SavetoTextAreaValueChanged(app, event)
            app.SavetoTextArea.Value = fullfile(app.SelectDirectoryTextArea.Value, "make_json.json");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetadataWizardpage33Panel = uipanel(app.UIFigure);
            app.MetadataWizardpage33Panel.Title = 'Metadata Wizard (page 3/3)';
            app.SubjectInfoLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectInfoLabel.Text = 'Subject Info';
            app.SubmitButton = uibutton(app.MetadataWizardpage33Panel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.SubjectSpeciesDropDownLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectSpeciesDropDownLabel.Text = 'Subject Species';
            app.SubjectSpeciesDropDown = uidropdown(app.MetadataWizardpage33Panel);
            app.SubjectSpeciesDropDown.Items = {'please select', 'mus musculus (mouse)', 'rattus (rat)', 'homo sapien (human)'};
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePickerLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectDOBDatePickerLabel.Text = 'Subject DOB';
            app.SubjectDOBDatePicker = uidatepicker(app.MetadataWizardpage33Panel);
            app.SubjectDOBDatePicker.Limits = [datetime([2010 1 1]) datetime([2023 4 7])];
            app.SubjectDOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubjectSexDropDownLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectSexDropDownLabel.Text = 'Subject Sex';
            app.SubjectSexDropDown = uidropdown(app.MetadataWizardpage33Panel);
            app.SubjectSexDropDown.Items = {'please select', 'male', 'female', 'unknown'};
            app.SubjectSexDropDown.Placeholder = 'please select';
            app.SubjectSexDropDown.Value = 'please select';
            app.BackButton_3 = uibutton(app.MetadataWizardpage33Panel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.SubjectIDEditFieldLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.MetadataWizardpage33Panel, 'text');
            app.SubjectStrainEditFieldLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SubjectStrainEditFieldLabel.Text = 'Subject Strain';
            app.SubjectStrainEditField = uieditfield(app.MetadataWizardpage33Panel, 'text');
            app.SavetoTextAreaLabel = uilabel(app.MetadataWizardpage33Panel);
            app.SavetoTextAreaLabel.Enable = 'off';
            app.SavetoTextAreaLabel.Visible = 'off';
            app.SavetoTextAreaLabel.Text = 'Save to';
            app.SavetoTextArea = uitextarea(app.MetadataWizardpage33Panel);
            app.SavetoTextArea.ValueChangedFcn = createCallbackFcn(app, @SavetoTextAreaValueChanged, true);
            app.SavetoTextArea.Editable = 'off';
            app.SavetoTextArea.Enable = 'off';
            app.SavetoTextArea.Visible = 'off';
            app.Label = uilabel(app.MetadataWizardpage33Panel);
            app.Label.Text = '';
            app.MetadataWizardpage23Panel = uipanel(app.UIFigure);
            app.MetadataWizardpage23Panel.Title = 'Metadata Wizard (page 2/3)';
            app.InstitutionEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.InstitutionEditField.Value = 'University of Rochester';
            app.ExperimenterInfoLabel = uilabel(app.MetadataWizardpage23Panel);
            app.ExperimenterInfoLabel.Text = 'Experimenter Info';
            app.SessionInfoLabel = uilabel(app.MetadataWizardpage23Panel);
            app.SessionInfoLabel.Text = 'Session Info';
            app.KeywordsTextAreaLabel = uilabel(app.MetadataWizardpage23Panel);
            app.KeywordsTextAreaLabel.Text = 'Keywords';
            app.KeywordsTextArea = uitextarea(app.MetadataWizardpage23Panel);
            app.KeywordsTextArea.Placeholder = 'eg., BPN Zstack, B-act Zstack';
            app.DescriptionTextAreaLabel = uilabel(app.MetadataWizardpage23Panel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.MetadataWizardpage23Panel);
            app.NextButton_2 = uibutton(app.MetadataWizardpage23Panel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.BackButton_2 = uibutton(app.MetadataWizardpage23Panel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.LastNameEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.FirstNameEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.InstitutionEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.InstitutionEditFieldLabel.Text = 'Institution';
            app.SessionIDEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.SessionIDEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.SessionIDEditField.Placeholder = 'eg., 20210511-m1';
            app.IdentifierEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.MetadataWizardpage23Panel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.StartTimeEditField.Placeholder = '(yyyy-MM-dd HH:mm:ss)';
            app.ErrorMessageLabel = uilabel(app.MetadataWizardpage23Panel);
            app.ErrorMessageLabel.Text = '';
            app.EditField = uieditfield(app.MetadataWizardpage23Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.MetadataWizardpage13Panel = uipanel(app.UIFigure);
            app.MetadataWizardpage13Panel.Title = 'Metadata Wizard (page 1/3)';
            app.MetadataWizardLabel = uilabel(app.MetadataWizardpage13Panel);
            app.MetadataWizardLabel.Text = 'Metadata Wizard';
            app.NextButton = uibutton(app.MetadataWizardpage13Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectDirectoryTextArea = uitextarea(app.MetadataWizardpage13Panel);
            app.SelectDirectoryTextArea.ValueChangedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.SelectDirectoryTextArea.Editable = 'off';
            app.selectdirectoryButton = uibutton(app.MetadataWizardpage13Panel, 'push');
            app.selectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.selectdirectoryButton.Text = 'select directory';
            app.SessionInfoLabel_2 = uilabel(app.MetadataWizardpage13Panel);
            app.SessionInfoLabel_2.Text = {'Welcome to Metadata Wizard! This tool guides you to enter the experiment session info according the the NWB specifications. '; ''; 'First, please select the directory to write your metadata.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metadata_wizard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end